classdef WormCorr_flex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        loadDatabodyButton           matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        showimageButton              matlab.ui.control.Button
        hatchCheckBox                matlab.ui.control.CheckBox
        M1CheckBox                   matlab.ui.control.CheckBox
        M2CheckBox                   matlab.ui.control.CheckBox
        M3CheckBox                   matlab.ui.control.CheckBox
        M4CheckBox                   matlab.ui.control.CheckBox
        timeEditFieldLabel           matlab.ui.control.Label
        timeEditField                matlab.ui.control.NumericEditField
        stagepositionEditFieldLabel  matlab.ui.control.Label
        stagepositionEditField       matlab.ui.control.NumericEditField
        hatchButton                  matlab.ui.control.Button
        M1Button                     matlab.ui.control.Button
        M2Button                     matlab.ui.control.Button
        M3Button                     matlab.ui.control.Button
        M4Button                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        loadredButton                matlab.ui.control.Button
        selecttimeButton             matlab.ui.control.Button
        loadgreenButton              matlab.ui.control.Button
        loadblueButton               matlab.ui.control.Button
        loadData2Button              matlab.ui.control.Button
        flankingButton               matlab.ui.control.Button
        RButton                      matlab.ui.control.Button
        GButton                      matlab.ui.control.Button
        BButton                      matlab.ui.control.Button
        flankingButton_2             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data         % Data loaded from doc2.mat
        dataC        % Data after manual correction
        s            % current stage positioin
        t            % current time position
        buttonPressed % which button was pressed last time
        TimeButtonPressed %was the time button pressed?
        path % Description
        flnm %filename of input data volume
        ch1path %path of images to be displayed
        flnm_mapping_ch1 % filename mapping of channel 1 images
        ch1_files %image files of  channel 1
        ch1_loaded %are ch1 images loaded
        
        ch2path %path of images to be displayed
        flnm_mapping_ch2 % filename mapping of channel 2 images
        ch2_files
        ch2_loaded %are ch2 images loaded
        
        ch3path %path of images to be displayed
        flnm_mapping_ch3 % filename mapping of channel 3 images
        ch3_files
        ch3_loaded %are ch2 images loaded
        
        showimagebuttonpressed % Description
        flnm_mapping % Description
        str_seg_filenames % Description
        
        flnames_ch1 %names of image files loaded for channel 1
        flnames_ch2
        flnames_ch3
        
        data1Loaded
        data2loaded
        
        data2
    end
    
    methods (Access = private)
        
        function updateDisplay1(app)
        if app.data1Loaded    
            yyaxis(app.UIAxes,'left');
           
        hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.data.vol(app.s,:),'b.');
            hold(app.UIAxes,'on');
            tworm = find(app.data.strClass(app.s,:)=='w');
            plot(app.UIAxes, tworm, app.data.vol(app.s,tworm),'r.');
            
            if isfield(app.data, 'tworms')

                'yes'
                t_two_worm = find(app.data.tworms(app.s,:)<10 & app.data.tworms(app.s,:)>0 & isfinite(app.data.tworms(app.s,:)))
                app.data.vol(app.s,:)
                app.data.tworms(app.s,:)
                plot(app.UIAxes, t_two_worm, app.data.vol(app.s,t_two_worm),'kv');
            else
                'no'
               
            end
            app.data.vol(app.s,:);
            noworm = find(isnan(app.data.vol(app.s,:)));
            for ind = noworm
                line(app.UIAxes,[ind ind], [1e4 1e6],'Color','green');
            end
            
            x = isfinite(app.dataC.ecdys(app.s,:));
            plot(app.UIAxes, app.dataC.ecdys(app.s,x), app.dataC.volAtEcdysis(app.s,x),'s');
            for xi = find(x)
                text(app.UIAxes, app.dataC.ecdys(app.s,xi)+10, app.dataC.volAtEcdysis(app.s,xi),[num2str(xi-1)]);
            end

            
            x = app.timeEditField.Value;
         
            if x>0 & x<size(app.dataC.vol,2)+1
                plot(app.UIAxes,x,app.dataC.vol(app.s,x),'Marker','o','Color',[0 0.7 0]);
            end
            
            set(app.UIAxes,'YScale','log');
            
%             if isfinite(app.dataC.ecdys(app.s,1)), app.hatchCheckBox.Value = 1;
% 
%             else, app.hatchCheckBox.Value = 1; end
%             
            title(app.UIAxes,num2str(app.s));
            ylim(app.UIAxes,[ 1e4 1e7]);
        end
        
        
        if app.data2loaded
                
        yyaxis(app.UIAxes,'right');           
        hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.data2.vol(app.s,:),'.','Color',[0 0.7 0]);
           
            set(app.UIAxes,'YScale','log');

        end
        yyaxis(app.UIAxes,'left');


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadDatabodyButton
        function loadDatabodyButtonPushed(app, event)
            [flnm, dtpath] = uigetfile('C:\Data\towbin\UniBE\tempimages\smalltestdataset\');
            app.data = load([dtpath flnm]);
            app.flnm = flnm;
            app.path = dtpath;
            
            app.dataC = app.data;
            app.s=0;
            Button_4Pushed(app, event);
            app.data1Loaded = true;
            updateDisplay1(app);
            
%                         
%             str_seg_filenames = dir([fileparts(fileparts(dtpath)) '/str_seg_ch1/']);
% %             
%             for k = 1:length(str_seg_filenames)
%                 flnm = str_seg_filenames(k).name;
%                 matchStr = regexp(flnm,'_Time[0-9]+_','match');
%                 if ~isempty(matchStr)
%                     t = str2double(matchStr{1}(6:end-1))+1;
%                     matchStr = regexp(flnm,'_Point[0-9]+_','match');
%                     if ~isempty(matchStr)
%                         s = str2num(matchStr{1}(7:end-1))+1;
%                             flnm_mapping(s,t) = k;  
%                     end
%                 end
%             end
%             flnm_mapping(flnm_mapping == 0) = NaN;
%             app.flnm_mapping = flnm_mapping;
%             app.str_seg_filenames = str_seg_filenames;
%             disp(app.str_seg_filenames);


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.timeEditField.Value = app.timeEditField.Value -1;
            timeEditFieldValueChanged(app, event);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.s<size(app.data.vol,1), app.s=app.s+1;end
            chkboxes = [app.hatchCheckBox, app.M1CheckBox, app.M2CheckBox, app.M3CheckBox, app.M4CheckBox];
            for k = 1:length(chkboxes)
                chkboxes(k).Value = isfinite(app.dataC.ecdys(app.s,k));
            end
            app.stagepositionEditField.Value = app.s;
%             app.hatchCheckBox.Value = isfinite(app.dataC.ecdys(app.s,1));
            updateDisplay1(app);

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.s>1, app.s=app.s-1;end
                chkboxes = [app.hatchCheckBox, app.M1CheckBox, app.M2CheckBox, app.M3CheckBox, app.M4CheckBox];
            for k = 1:length(chkboxes)
                chkboxes(k).Value = isfinite(app.dataC.ecdys(app.s,k));
            end
            app.stagepositionEditField.Value = app.s;

%             app.hatchCheckBox.Value = isfinite(app.dataC.ecdys(app.s,1));
            updateDisplay1(app);
        end

        % Value changed function: hatchCheckBox
        function hatchCheckBoxValueChanged(app, event)
            value = app.hatchCheckBox.Value;
            if value == 0
                app.dataC.ecdys(app.s,1) = NaN;
                app.dataC.volAtEcdysis(app.s,1) = NaN;

            else
                app.dataC.ecdys(app.s,1) = app.data.ecdys(app.s,1);
                app.dataC.volAtEcdysis(app.s,1) = app.data.volAtEcdysis(app.s,1);

            end
            
            updateDisplay1(app)
                        
        end

        % Value changed function: M1CheckBox
        function M1CheckBoxValueChanged(app, event)
            value = app.M1CheckBox.Value;
            if value == 0
                app.dataC.ecdys(app.s,2) = NaN;
                app.dataC.volAtEcdysis(app.s,2) = NaN;

            else
                app.dataC.ecdys(app.s,2) = app.data.ecdys(app.s,2);
                app.dataC.volAtEcdysis(app.s,2) = app.data.volAtEcdysis(app.s,2);
            end
            
            updateDisplay1(app)
        end

        % Value changed function: M2CheckBox
        function M2CheckBoxValueChanged(app, event)
            value = app.M2CheckBox.Value;
            if value == 0
                app.dataC.ecdys(app.s,3) = NaN;
                app.dataC.volAtEcdysis(app.s,3) = NaN;

            else
                app.dataC.ecdys(app.s,3) = app.data.ecdys(app.s,3);
                app.dataC.volAtEcdysis(app.s,3) = app.data.volAtEcdysis(app.s,3);
            end
            
            updateDisplay1(app)
        end

        % Value changed function: M3CheckBox
        function M3CheckBoxValueChanged(app, event)
            value = app.M3CheckBox.Value;
            if value == 0
                app.dataC.ecdys(app.s,4) = NaN;
                app.dataC.volAtEcdysis(app.s,4) = NaN;

            else
                app.dataC.ecdys(app.s,4) = app.data.ecdys(app.s,4);
                app.dataC.volAtEcdysis(app.s,4) = app.data.volAtEcdysis(app.s,4);
            end
            
            updateDisplay1(app)
        end

        % Value changed function: M4CheckBox
        function M4CheckBoxValueChanged(app, event)
            value = app.M4CheckBox.Value;
            if value == 0
                app.dataC.ecdys(app.s,5) = NaN;
                app.dataC.volAtEcdysis(app.s,5) = NaN;

            else
                app.dataC.ecdys(app.s,5) = app.data.ecdys(app.s,5);
                app.dataC.volAtEcdysis(app.s,5) = app.data.volAtEcdysis(app.s,5);
            end
            
            updateDisplay1(app)
        end

        % Button pushed function: hatchButton
        function hatchButtonPushed(app, event)
                app.buttonPressed = 1;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.showimagebuttonpressed > 0
                temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
                loc = max(round(temp(1,1)),1);
                if isfinite(app.flnm_mapping(app.s,loc))
                im = imread([fileparts(fileparts(app.path)) '/str_seg/' app.str_seg_filenames(app.flnm_mapping(app.s,loc)).name]);
                imshow(im,[0 1],'parent',app.UIAxes2);
                app.timeEditField.Value = loc;
                end
                app.showimagebuttonpressed=0;
            end
            
            if app.buttonPressed > 0
                temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
                loc = [ temp(1,1), temp(1,2)]; % Gets the (x,y) coordinates
                app.dataC.ecdys(app.s,app.buttonPressed) = loc(1);
                app.dataC.volAtEcdysis(app.s,app.buttonPressed) = loc(2);
                updateDisplay1(app);
                chkboxes = [app.hatchCheckBox, app.M1CheckBox, app.M2CheckBox, app.M3CheckBox, app.M4CheckBox];
                chkboxes(app.buttonPressed).Value = 1;

                app.buttonPressed=0;
            end
            
            if app.TimeButtonPressed == 1
                temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
                loc = [ temp(1,1), temp(1,2)]; % Gets the (x,y) coordinates
                app.timeEditField.Value = round(loc(1));
                timeEditFieldValueChanged(app, event);

                app.TimeButtonPressed=0;
            end
            
             
        end

        % Button pushed function: M1Button
        function M1ButtonPushed(app, event)
                            app.buttonPressed = 2;

        end

        % Button pushed function: M2Button
        function M2ButtonPushed(app, event)
                            app.buttonPressed = 3;

        end

        % Button pushed function: M3Button
        function M3ButtonPushed(app, event)
                            app.buttonPressed = 4;

        end

        % Button pushed function: M4Button
        function M4ButtonPushed(app, event)
                            app.buttonPressed = 5;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        fullfile(app.path, app.flnm, '_cor.mat')
        [~, flnmO, ~] = fileparts(app.flnm);
        flnmO = [flnmO '_cor.mat']
        [flnm, dtpath] = uiputfile(fullfile(app.path, flnmO));

        vol = app.dataC.vol;
        strClass = app.dataC.strClass
        ecdys = app.dataC.ecdys;
        volAtEcdysis = app.dataC.volAtEcdysis;
        
        dataC = app.dataC;
        data = app.data;
            save(fullfile(dtpath, flnm), '-struct', 'dataC');
        end

        % Value changed function: stagepositionEditField
        function stagepositionEditFieldValueChanged(app, event)
            value = app.stagepositionEditField.Value;
            if value>0 & value <= size(app.dataC.vol,1)
               app.s = value;
               updateDisplay1(app);
            end
        end

        % Button pushed function: showimageButton
        function showimageButtonPushed(app, event)
        t = app.timeEditField.Value;
        s = app.s;
        if app.ch1_loaded
            if isfinite(app.flnames_ch1{s,t})
                if exist(app.flnames_ch1{s,t})
                       r = imread(app.flnames_ch1{s,t});
                elseif exist(strrep(app.flnames_ch1{s,t},'/mnt','\\izbkingston'))
                       r = imread(strrep(app.flnames_ch1{s,t},'/mnt','\\izbkingston'));
                elseif exist(strrep(strrep(app.flnames_ch1{s,t},'/mnt','\\izbkingston'),'external.data','external.data/TowbinLab'))
                       r = imread(strrep(strrep(app.flnames_ch1{s,t},'/mnt','\\izbkingston'),'external.data','external.data/TowbinLab'));
                elseif exist(strrep(strrep(app.flnames_ch1{s,t},'/mnt','\\izbkingston'),'imaging.data','external.data/TowbinLab'))
                        r = imread(strrep(strrep(app.flnames_ch1{s,t},'/mnt','\\izbkingston'),'imaging.data','external.data/TowbinLab'));
                end
                               
               if max(r(:) == 1)
                   r = rescale(bwperim(r));
               else
                   r = rescale(r);
               end
                
           end
             
           
        end
        
        if app.ch2_loaded
            if isfinite(app.flnames_ch2{s,t})
                
                if exist(app.flnames_ch2{s,t})
                       g = imread(app.flnames_ch2{s,t});
                elseif exist(strrep(app.flnames_ch2{s,t},'/mnt','\\izbkingston'))
                       g = imread(strrep(app.flnames_ch2{s,t},'/mnt','\\izbkingston'));
                elseif exist(strrep(strrep(app.flnames_ch2{s,t},'/mnt','\\izbkingston'),'external.data','external.data/TowbinLab'))
                       g = imread(strrep(strrep(app.flnames_ch2{s,t},'/mnt','\\izbkingston'),'external.data','external.data/TowbinLab'));
                elseif exist(strrep(strrep(app.flnames_ch2{s,t},'/mnt','\\izbkingston'),'imaging.data','external.data/TowbinLab'))
                        g = imread(strrep(strrep(app.flnames_ch2{s,t},'/mnt','\\izbkingston'),'imaging.data','external.data/TowbinLab'));
                end
                
               if max(g(:) == 1)
                   g = rescale(bwperim(g));
               else
                   g = rescale(g);
               end
            end
        else 
            g = zeros(size(r));
           
        end
        
        if app.ch3_loaded
          if isfinite(app.flnames_ch3{s,t})
              
              if exist(app.flnames_ch3{s,t})
                       b = imread(app.flnames_ch3{s,t});
                elseif exist(strrep(app.flnames_ch3{s,t},'/mnt','\\izbkingston'))
                       b = imread(strrep(app.flnames_ch3{s,t},'/mnt','\\izbkingston'));
                elseif exist(strrep(strrep(app.flnames_ch3{s,t},'/mnt','\\izbkingston'),'external.data','external.data/TowbinLab'))
                       b = imread(strrep(strrep(app.flnames_ch3{s,t},'/mnt','\\izbkingston'),'external.data','external.data/TowbinLab'));
                elseif exist(strrep(strrep(app.flnames_ch3{s,t},'/mnt','\\izbkingston'),'imaging.data','external.data/TowbinLab'))
                        b = imread(strrep(strrep(app.flnames_ch3{s,t},'/mnt','\\izbkingston'),'imaging.data','external.data/TowbinLab'));
                end
                             
               if max(b(:) == 1)
                   b = rescale(bwperim(b));
               else
                   b = rescale(b);
               end
          end
        else
            b = zeros(size(r));
        end
        im = cat(3,r,g,b);
        imshow(im, [0,1], 'Parent', app.UIAxes2)
        updateDisplay1(app);
        
        
        end

        % Value changed function: timeEditField
        function timeEditFieldValueChanged(app, event)
            loc = app.timeEditField.Value;
            if loc>0 
%                 if isfinite(app.flnm_mapping(app.s,loc))
                    app.timeEditField.Value = loc;
                    showimageButtonPushed(app, event)
%                 end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.timeEditField.Value = app.timeEditField.Value +1;
            timeEditFieldValueChanged(app, event);
        end

        % Button pushed function: loadredButton
        function loadredButtonPushed(app, event)
               [flnm, dtpath] = uigetfile(fullfile(app.path));
               [~,~,ext] = fileparts(flnm)
               
               if ext =='.mat'
               
               
                   temp = load(fullfile(dtpath, flnm));
                   if isfield(temp, 'flnmmap')
                       app.flnames_ch1 = temp.flnmmap;
                   end
               app.ch1_loaded = true;
               else
                   
               end
               
        end

        % Button pushed function: selecttimeButton
        function selecttimeButtonPushed(app, event)
                                        app.TimeButtonPressed = 1;
        end

        % Button pushed function: loadgreenButton
        function loadgreenButtonPushed(app, event)
               [flnm, dtpath] = uigetfile(fullfile(app.path));
               [~,~,ext] = fileparts(flnm)
               
               if ext =='.mat'
               
               
                   temp = load(fullfile(dtpath, flnm));
                   if isfield(temp, 'flnmmap')
                       app.flnames_ch2 = temp.flnmmap;
                   end
               app.ch2_loaded = true;
               else
                   
               end
               

        end

        % Button pushed function: loadblueButton
        function loadblueButtonPushed(app, event)
               [flnm, dtpath] = uigetfile(fullfile(app.path));
               [~,~,ext] = fileparts(flnm)
               
               if ext =='.mat'
               
               
                   temp = load(fullfile(dtpath, flnm));
                   if isfield(temp, 'flnmmap')
                       app.flnames_ch3 = temp.flnmmap;
                   end
               app.ch3_loaded = true;
               else
                   
               end
        end

        % Button pushed function: loadData2Button
        function loadData2ButtonPushed(app, event)
            [flnm, dtpath] = uigetfile('C:\Data\towbin\UniBE\tempimages\smalltestdataset\');
            app.data2 = load([dtpath flnm]);
%             app.flnm = flnm;
%             app.path = dtpath;
                       
            app.data2loaded = true;
            updateDisplay1(app);
        end

        % Button pushed function: flankingButton
        function flankingImages(app, event)
            f = figure
%             
        t = app.timeEditField.Value;
        s = app.s;
        count = 0;
        ha = tight_subplot(3,5,[.01 .03],[.1 .01],[.01 .01])
        for n = max(t-7,1):min(t+7,numel(app.data.vol(s,:) ))
            count = count+1;
            if app.ch1_loaded & (exist(app.flnames_ch1{s,n}) | exist(strrep(app.flnames_ch1{s,n},'/mnt','\\izbkingston')))
                
                if exist(app.flnames_ch1{s,n}) 
                       r = imread(app.flnames_ch1{s,n});
                else
                       r = imread(strrep(app.flnames_ch1{s,n},'/mnt','\\izbkingston'));
                end
                
                if max(r(:) == 1)
                   r = rescale(bwperim(r));
                else
                   r = rescale(r);
                end
            else
                   r = NaN;
            end
            
            if app.ch2_loaded & (exist(app.flnames_ch2{s,n}) | exist(strrep(app.flnames_ch2{s,n},'/mnt','\\izbkingston')))
                 if exist(app.flnames_ch2{s,n})
                       g = imread(app.flnames_ch2{s,n});
                else
                       g = imread(strrep(app.flnames_ch2{s,n},'/mnt','\\izbkingston'));
                 end
                
                if max(g(:) == 1)
                   g = rescale(bwperim(g));
                else
                   g = rescale(g);
                end
            else
                   g = NaN;

            end
            
            if app.ch3_loaded & (exist(app.flnames_ch3{s,n}) | exist(strrep(app.flnames_ch3{s,n},'/mnt','\\izbkingston')))
                if exist(app.flnames_ch3{s,n})
                       b = imread(app.flnames_ch2{s,n});
                else
                       b = imread(strrep(app.flnames_ch3{s,n},'/mnt','\\izbkingston'));
                end
                
                if max(b(:) == 1)
                   b = rescale(bwperim(b));
                else
                   b = rescale(b);
                end
            else
                 b = NaN;
            end
            images = {r,g,b};
            realimage = find (~isnan([r g b]));
            if ~isempty(realimage)
                imagesize = size(images{realimage(1)});
            end
            
            if isnan(r)
                r = zeros(imagesize)
            end
            if isnan(g)
                g = zeros(imagesize)
            end
            if isnan(b)
                b = zeros(imagesize)
            end
            
            axes(ha(count));
            set(ha(1:4),'XTickLabel','');
            set(ha,'YTickLabel','')
            im = cat(3,r,g,b);

            imshow(im);
            if n==t
                title(num2str(t));
            
            end
            

        end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.loadDatabodyButton = uibutton(app.UIFigure, 'push');
            app.loadDatabodyButton.ButtonPushedFcn = createCallbackFcn(app, @loadDatabodyButtonPushed, true);
            app.loadDatabodyButton.Text = 'load Data body';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.showimageButton = uibutton(app.UIFigure, 'push');
            app.showimageButton.ButtonPushedFcn = createCallbackFcn(app, @showimageButtonPushed, true);
            app.showimageButton.Text = 'show image';
            app.hatchCheckBox = uicheckbox(app.UIFigure);
            app.hatchCheckBox.ValueChangedFcn = createCallbackFcn(app, @hatchCheckBoxValueChanged, true);
            app.hatchCheckBox.Text = 'hatch';
            app.M1CheckBox = uicheckbox(app.UIFigure);
            app.M1CheckBox.ValueChangedFcn = createCallbackFcn(app, @M1CheckBoxValueChanged, true);
            app.M1CheckBox.Text = 'M1';
            app.M2CheckBox = uicheckbox(app.UIFigure);
            app.M2CheckBox.ValueChangedFcn = createCallbackFcn(app, @M2CheckBoxValueChanged, true);
            app.M2CheckBox.Text = 'M2';
            app.M3CheckBox = uicheckbox(app.UIFigure);
            app.M3CheckBox.ValueChangedFcn = createCallbackFcn(app, @M3CheckBoxValueChanged, true);
            app.M3CheckBox.Text = 'M3';
            app.M4CheckBox = uicheckbox(app.UIFigure);
            app.M4CheckBox.ValueChangedFcn = createCallbackFcn(app, @M4CheckBoxValueChanged, true);
            app.M4CheckBox.Text = 'M4';
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time ';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeEditField.ValueChangedFcn = createCallbackFcn(app, @timeEditFieldValueChanged, true);
            app.stagepositionEditFieldLabel = uilabel(app.UIFigure);
            app.stagepositionEditFieldLabel.Text = 'stage position';
            app.stagepositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.stagepositionEditField.ValueChangedFcn = createCallbackFcn(app, @stagepositionEditFieldValueChanged, true);
            app.hatchButton = uibutton(app.UIFigure, 'push');
            app.hatchButton.ButtonPushedFcn = createCallbackFcn(app, @hatchButtonPushed, true);
            app.hatchButton.Text = 'hatch';
            app.M1Button = uibutton(app.UIFigure, 'push');
            app.M1Button.ButtonPushedFcn = createCallbackFcn(app, @M1ButtonPushed, true);
            app.M1Button.Text = 'M1';
            app.M2Button = uibutton(app.UIFigure, 'push');
            app.M2Button.ButtonPushedFcn = createCallbackFcn(app, @M2ButtonPushed, true);
            app.M2Button.Text = 'M2';
            app.M3Button = uibutton(app.UIFigure, 'push');
            app.M3Button.ButtonPushedFcn = createCallbackFcn(app, @M3ButtonPushed, true);
            app.M3Button.Text = 'M3';
            app.M4Button = uibutton(app.UIFigure, 'push');
            app.M4Button.ButtonPushedFcn = createCallbackFcn(app, @M4ButtonPushed, true);
            app.M4Button.Text = 'M4';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<<';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.loadredButton = uibutton(app.UIFigure, 'push');
            app.loadredButton.ButtonPushedFcn = createCallbackFcn(app, @loadredButtonPushed, true);
            app.loadredButton.Text = 'load red';
            app.selecttimeButton = uibutton(app.UIFigure, 'push');
            app.selecttimeButton.ButtonPushedFcn = createCallbackFcn(app, @selecttimeButtonPushed, true);
            app.selecttimeButton.Text = 'select time';
            app.loadgreenButton = uibutton(app.UIFigure, 'push');
            app.loadgreenButton.ButtonPushedFcn = createCallbackFcn(app, @loadgreenButtonPushed, true);
            app.loadgreenButton.Text = 'load green';
            app.loadblueButton = uibutton(app.UIFigure, 'push');
            app.loadblueButton.ButtonPushedFcn = createCallbackFcn(app, @loadblueButtonPushed, true);
            app.loadblueButton.Text = 'load blue';
            app.loadData2Button = uibutton(app.UIFigure, 'push');
            app.loadData2Button.ButtonPushedFcn = createCallbackFcn(app, @loadData2ButtonPushed, true);
            app.loadData2Button.Text = 'load Data 2';
            app.flankingButton = uibutton(app.UIFigure, 'push');
            app.flankingButton.ButtonPushedFcn = createCallbackFcn(app, @flankingImages, true);
            app.flankingButton.Text = 'flanking';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.Text = 'R';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.Text = 'G';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.Text = 'B';
            app.flankingButton_2 = uibutton(app.UIFigure, 'push');
            app.flankingButton_2.Text = 'flanking';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WormCorr_flex

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end